classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FossilUI             matlab.ui.Figure
        FileMenu             matlab.ui.container.Menu
        AboutMenu            matlab.ui.container.Menu
        OpenFileMenu         matlab.ui.container.Menu
        ProgramInfoMenu      matlab.ui.container.Menu
        EditMenu             matlab.ui.container.Menu
        LeftPanel            matlab.ui.container.Panel
        EditorPane1          matlab.ui.control.TextArea
        RightPanel           matlab.ui.container.Panel
        RightTabGroup        matlab.ui.container.TabGroup
        OutputTab            matlab.ui.container.Tab
        OutputPane           matlab.ui.control.HTML
        RUNButton            matlab.ui.control.Button
        ProgramNameLabel     matlab.ui.control.Label
        ProgramName          matlab.ui.control.EditField
        ExtensionsDropDown   matlab.ui.control.DropDown
        CLEARButton          matlab.ui.control.Button
        SyntaxTab            matlab.ui.container.Tab
        SyntaxPane           matlab.ui.control.HTML
        CHECKSYNTAXButton    matlab.ui.control.Button
        CompileOptionsTab    matlab.ui.container.Tab
        chckbx1              matlab.ui.control.CheckBox
        UpdateOptionsButton  matlab.ui.control.Button
        chckbx2              matlab.ui.control.CheckBox
        chckbx3              matlab.ui.control.CheckBox
        chckbx4              matlab.ui.control.CheckBox
        chckbx5              matlab.ui.control.CheckBox
        FolderTreeTab        matlab.ui.container.Tab
        Tree                 matlab.ui.container.Tree
        Node                 matlab.ui.container.TreeNode
        Node2                matlab.ui.container.TreeNode
        Node3                matlab.ui.container.TreeNode
        Node4                matlab.ui.container.TreeNode
    end

    properties (Access = public)
        
        %%%% --- SET FOSSIL PROGRAM INFORMATION --- %%%%
        ver = '0.92'; % current version of the app
        release_date = 'October 11, 2020'; % release date of this version
        %ss = get(groot, "ScreenSize"); % [x, y, width, height]
        
        %%% --- SAVE GLOBAL VARIABLES --- %%%
        % output_history: History for the output pane.
        output_history = "";
        
        % ind: saves index to swap between file names (see syntax pane)
        ind = 0;
        
        % compiling options: selected in the compile pane to use specific
        %  instructions
        compile_options = {'-ffree-form', '-Xlist', '-g', '-fast', '-O[1]'};
        compile_option_names = {'chckbx1', 'chckbx2', 'chckbx3', 'chckbx4', 'chckbx5'};
        selected = [1 0 0 0 0];
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, infoapp)
            
            %%%%%% ----- for UI layout, see docs/LAYOUT.md ----- %%%%%%
            cd ~/Documents/GitHub/Fossil
            
            
            %%%% --- INITIALIZE OBJECT POSITIONS --- %%%%
            % FOSSIL UI = full screen
            app.FossilUI.Position = get(groot, "ScreenSize"); % = app.screen_size
            % set Fossil UI name to "Fossil v[version] - [/dir]"
            app.FossilUI.Name = ['Fossil v' app.ver ' - ' pwd];
            %ss = get(groot, "ScreenSize"); % [x, y, width, height]
            
            %{
            %% LEFT PANEL component positions %%
            % Editor pane on main stage, spare room above
            app.LeftPanel.Position    = [1, 1, ss(3)/2, ss(4)];
            %app.LeftTabGroup.Position = [10, 10, 700, 765];
            app.EditorPane1.Position  = [10, 10, ss(3)/2-20, ss(4)-210];
            
            %% RIGHT PANEL component positions %%
            % Tabs dimensions are same as panel minus a small amount to fit
            app.RightPanel.Position    = [ss(3)/2, 1, ss(3)/2, ss(4)];
            app.RightTabGroup.Position = [10, 10, 700, 765];
            % ------ TAB 1 - OUTPUT ------ %
            app.OutputPane.Position         = [20, 20, 660, 670];
            app.RUNButton.Position          = [20, 700, 80, 30];
            app.ProgramName.Position        = [190, 700, 140, 30];
            app.ProgramNameLabel.Position   = [70, 700, 120, 30];
            app.ExtensionsDropDown.Position = [340, 700, 60, 30];
            %}
            %{
            % ------ TAB 2 - SYNTAX ------ %
            app.SyntaxPane.Position = [20, 20, ...
                app.ss(3)/2-60, app.ss(4)-230];
            app.CHECKSYNTAXButton.Position = [20, app.ss(4)-200, 160, 30];
            
            % ------ TAB 3 - COMPILE OPTIONS ------ %
            app.UpdateOptionsButton.Position = [20, app.ss(4)-200, 160, 30];
            app.chckbx1.Position = [20 app.ss(4)-240 120 20];
            app.chckbx2.Position = [20 app.ss(4)-260 200 20];
            app.chckbx3.Position = [20 app.ss(4)-280 120 20];
            app.chckbx4.Position = [20 app.ss(4)-300 200 20];
            app.chckbx5.Position = [20 app.ss(4)-320 120 20];
            %}


            %%%% --- INITIALIZE OBJECT COLORS & FONTS --- %%%%
            % set Fossil UI name to "Fossil v[version] - [/dir]"
            app.ProgramNameLabel.FontSize = 12;
            app.EditorPane1.FontSize = 13;
            app.EditorPane1.FontName = 'Geneva';
            app.EditorPane1.BackgroundColor = [0.94, 0.94, 0.94];
            app.ExtensionsDropDown.FontSize = 12;
            app.ExtensionsDropDown.FontName = 'Helvetica';


            %%%% --- INITIALIZE OBJECT STRINGS --- %%%%
            app.ProgramNameLabel.Text = "Program Name:";
            app.ExtensionsDropDown.Value = '.f90';
            app.ExtensionsDropDown.Items = {'.f90', '.f95', '.f03', ...
                '.f08', '.f', '.for', '.ftn'};


            %%%% --- SET DEFAULT PROGRAM: Hello World! --- %%%%
            % Set default as a simple Hello World Program
            HelloWorldStr = sprintf('%s\n%s\n%s\n', ...
                "PROGRAM hello", ...
                "  PRINT *,""Hello World!""", ...
                "END PROGRAM hello");
            set(app.EditorPane1, 'Value', HelloWorldStr);
            set(app.ProgramName, 'Value', "hello")


            %%%% --- INITIALIZE BASIC PANES IN TABS --- %%%%
            % Set default output pane to a set of instructions
            app.OutputPane.HTMLSource = fullfile(pwd,'tools/output/commandline.html');
            
            % Set default syntax HTML obj to data-driven HTML file and
            %  initialize with the default program
            app.SyntaxPane.HTMLSource = fullfile(pwd,'tools/syntax/syntax_template.html');


            %%%% --- HIDE UNUSED OBJECTS --- %%%%
            % Hide tabs for compiler options and the folder tree
            %{ app.SyntaxTab.Parent = [];%}
            app.CompileOptionsTab.Parent = [];
            app.FolderTreeTab.Parent = []; 
            
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            
            %%% --- Run compile command in terminal & show output --- %%%
            % Open a fortran file (type is based on extension drop-down 
            %  menu and write the code from the editor pane to it. The file
            %  name is stored in 'file_name' with file ID = file_ID.
            file_name = [get(app.ProgramName, 'Value') app.ExtensionsDropDown.Value];
            file_ID = fopen(['test/' file_name],'w');
            fprintf(file_ID,'%s\n', app.EditorPane1.Value{:});
            fclose(file_ID); % close file
            
            % get compiling options
            options = app.compile_options{app.selected == 1};
            
            % Run the terminal command, printing all good and bad outputs,
            %  flag is currently unused, build tab here?
            [~, output] = system(['cd ~/Documents/GitHub/Fossil/test; ', + ...
                '/usr/local/bin/gfortran ' options ' -o f ' file_name ';', + ...
                ' ./f']);
            
            %https://gcc.gnu.org/onlinedocs/gcc-5.1.0/gfortran.pdf
            
            % save timestamp for console window
            timestamp = sprintf('%s%s%s', ...
                "<time>", ...
                [file_name ' | ' string(datetime('now'))], ...
                "</time>");
            
            % write the last output to history for console
            app.output_history = [app.output_history sprintf('\n%s%s%s', ...
                output), timestamp, "<br>"];
            
            
            %code = get(app.EditorPane1, 'Value');
            app.OutputPane.Data = sprintf('%s', app.output_history);
                        
            % Remove 3 different output files:
            %  - tools/temp, which is the entire temp folder
            %  - test/[program name].[ext], with the current program name
            %  - test/f.unix, exe file from compiling f90
            delete('tools/temp/*')
            if isfile(['test/' file_name])
                system(['cd ~/Documents/GitHub/Fossil/test; ', + ...
                    'rm ' file_name]);
            end
            if isfile('test/f')
                system(['cd ~/Documents/GitHub/Fossil/test; ', + ...
                    'rm f']);
            end
            
        end

        % Button pushed function: CLEARButton
        function CLEARButtonPushed(app, event)
            
            %% --- CLEAR & RESET OUTPUT PANE --- %%
            % Reset the 'output_history' global variable
            %  and replace data with blanks in the output pane
            app.output_history = "";
            app.OutputPane.Data = sprintf('%s', app.output_history);
            
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            
            %% --- ABOUT MENU creation/selection --- %%
            % open the about menu information: text, title, and image
            text = {'\fontsize{12}Programming IDE for antiquated languages,',...
                [' Current version = ' app.ver],...
                [' Released on ' app.release_date],...
                ' See the repository at https://github.com/nrwade0/Fossil'};
            title = "About Fossil IDE";
            [imgdata, imgcmap] = imread('res/car.jpg');
            
            % Change options to TeX interpreter, modal dialog box
            options.Interpreter = 'tex';
            options.WindowStyle = 'modal';
            
            % Create box and reposition in the middle
            m = msgbox(text, title, "custom", imgdata, imgcmap, options);
            ss = get(groot, "ScreenSize"); % [x, y, width, height]
            m.Position = [(ss(3)-330)/2 (ss(4)-90)/2 420 100];
            
        end

        % Button pushed function: CHECKSYNTAXButton
        function CHECKSYNTAXButtonPushed(app, event)
            
            %%% - OUTPUT FORTRAN SYNTAX: TRY 1 ALTERNATING HTML FILES - %%%
            % Retrieves the code from the editor pane and writes to the 
            %  connected syntax file 'fortran[app.ind].html'
            % NOTE: MATLAB doesn't refresh an HTML object unless it has a
            %  different name? Hence, the alternating index (app.ind) that
            %  determines the value after the file name.
            code = get(app.EditorPane1, 'Value');
            fortran_html = sprintf('%s\n', ...
                "<pre class=""line-numbers"" style=""font-size:12px"">", ...
                "  <link href=""prism.css"" rel=""stylesheet""/>", ...
                "  <script src=""prism.js""></script>", ...
                "  <code class=""language-fortran"">", ...
                code{:}, ...
                "  </code>",...
                "</pre>");
            
            % Opens an html file with ID = 'file_ID' and writes the above
            %  syntax-highlighted html to the file.
            file_name = ['fortran' num2str(app.ind) '.html'];
            file_ID = fopen(['tools/syntax/' file_name],'w');
            fprintf(file_ID,'%s\n', fortran_html);
            fclose(file_ID); % close file
            
            % write to the syntax pane
            app.SyntaxPane.HTMLSource = fullfile(pwd,['tools/syntax/' file_name]);
            
            % switch the index 0->1 or 1->0
            app.ind = 1 - app.ind;
            extra_file_name = ['fortran' num2str(app.ind) '.html'];
            
            % Remove the other syntax output file:
            if isfile(['tools/syntax/' extra_file_name])
                disp('hello')
                delete(['tools/syntax/' extra_file_name])
            end


            %{
            %%% - OUTPUT FORTRAN SYNTAX: TRY 2 DATACHANGED() FUNCTION - %%%
            % Gets the code data in the editor pane and writes to the 
            %  connected syntax file 'fortran.html' <div=DataDisplay>. 
            %
            % Can't yet find a way to determine when the data has changed
            %  with a <DIV ID> and like it to a specific css or js file
            %  with the FORTRAN language semantics.
            code = get(app.EditorPane1, 'Value');
            app.SyntaxPane.Data = sprintf('%s<br>', code{:});
            %}
            
        end

        % Button pushed function: UpdateOptionsButton
        function UpdateOptionsButtonPushed(app, event)
            
            %%% --- Determines the compile options selected --- %%%
            % We parse and examine the checkboxes by first isolating the
            %  UI objects with the phrase 'chckbox' in the name. Then we
            %  set a binary (0/1) columnar matrix (called 'selected') to
            %  store the Y/N of the checkboxes. The compile options are
            %  indexed using this matrix and the public property
            %  'app.compile_options'
            fieldNames = fieldnames(app);
            chckbxNames = fieldNames(contains(fieldNames,'chckbx'));
            
            % Binary array initialized then filled with 1's where boxes are
            %  checked.
            for i = 1:numel(chckbxNames)
                if(app.(chckbxNames{i}).Value == true)
                    app.selected(i) = 1;
                end
            end
            
            % Display selected options
            %fprintf('%s /n', app.compile_options{app.selected == 1});
            
        end

        % Menu selected function: OpenFileMenu
        function OpenFileMenuSelected(app, event)
            
            %%%% --- Open file selector and choose a file to open --- %%%%
            [file, path, ~] = uigetfile( ...
                {'*.f77; *.f90; *.f95; *.f03; *.f08; *.f; *.for; *.ftn', ...
                'Fortran Files (*.f77,*.f90,*.f95,*.f03,*.f08,*.f,*.for,*.ftn)';
                '*.dat; *.csv; *.txt','Data Files (*.dat,*.csv,*.txt)'});
            %fprintf('%s\n%s\n', file, path)
                        
            % open new tab with this file and display it's output onto
            % editor pane
            %num_tabs = num2str(length(app.LeftTabGroup.Children)+1);
            
            %eval(['EditorTab' No_tabs ' = uitab;']);
            %eval(['EditorPane' No_tabs ' = uitextarea;']);


            %%%% These two work!!! %%%
            %tab1 = uitab(app.LeftTabGroup,'Title','Settings');
            %eval(['tab' num_tabs ' = uitab(app.LeftTabGroup, "Title", "' file '")']);
            
            app.EditorPane1.Value = fullfile(path, file);
            fid = fopen([path file], 'r');
            app.EditorPane1.Value = fread(fid, inf, 'uint8=>char');
            fclose(fid);
            % EDIT FROM HERE AS tab# ----------


            %app.EditorTab2.Parent = app.LeftTabGroup;
                        
            %eval(['app.EditorTab' No_tabs '.Parent = app.LeftTabGroup;']);
            %eval(['app.EditorPane' No_tabs '.Parent = app.EditorTab' No_tabs ';']);
            
            %app.EditorPane1.Parent = app.RightTabGroup;
            %eval(['app.EditorTab' No_tabs '.parent = ''LeftTabGroup''']);
            %eval(['app.EditorTab' No_tabs '.Title = ' file]);
            %eval(['app.EditorTab' No_tabs '(''Parent'', "LeftTabGroup");']);
            %eval(['app.EditorTab' No_tabs '("Title", [path file]);']);
            
            %new_pane = ['app.EditorPane' num2str(length(app.LeftTabGroup.Children))];
            %uitextarea("Parent", new_pane, "Value", "Hello World program")
            %uitextarea("Parent", app.LeftTabGroup, "Value", "Hello World program")
            %eval(['app.EditorPane' num2str(length(app.LeftTabGroup.Children)) ...
           %     '.Position = [10, 10, app.ss(3)/2-40, app.ss(4)-180];'])
            %app.EditorPane1.Position = [10, 10, ...
           %     app.ss(3)/2-40, app.ss(4)-180];
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FossilUI = uifigure('Visible', 'off');
            app.FossilUI.Name = 'Fossil 0.9';
            app.FileMenu = uimenu(app.FossilUI);
            app.FileMenu.Text = 'File';
            app.AboutMenu = uimenu(app.FileMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.OpenFileMenu = uimenu(app.FileMenu);
            app.OpenFileMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenFileMenuSelected, true);
            app.OpenFileMenu.Text = 'Open File';
            app.ProgramInfoMenu = uimenu(app.FileMenu);
            app.ProgramInfoMenu.Text = 'Program Info';
            app.EditMenu = uimenu(app.FossilUI);
            app.EditMenu.Visible = 'off';
            app.EditMenu.Text = 'Edit';
            app.LeftPanel = uipanel(app.FossilUI);
            app.EditorPane1 = uitextarea(app.LeftPanel);
            app.RightPanel = uipanel(app.FossilUI);
            app.RightTabGroup = uitabgroup(app.RightPanel);
            app.OutputTab = uitab(app.RightTabGroup);
            app.OutputTab.Title = 'Output';
            app.OutputPane = uihtml(app.OutputTab);
            app.RUNButton = uibutton(app.OutputTab, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.ProgramNameLabel = uilabel(app.OutputTab);
            app.ProgramNameLabel.Text = 'Program Name';
            app.ProgramName = uieditfield(app.OutputTab, 'text');
            app.ExtensionsDropDown = uidropdown(app.OutputTab);
            app.ExtensionsDropDown.Items = {'.f90', '.f95', '.f03', '.f08', '.f', '.for', '.ftn'};
            app.ExtensionsDropDown.Value = '.f90';
            app.CLEARButton = uibutton(app.OutputTab, 'push');
            app.CLEARButton.ButtonPushedFcn = createCallbackFcn(app, @CLEARButtonPushed, true);
            app.CLEARButton.Text = 'CLEAR';
            app.SyntaxTab = uitab(app.RightTabGroup);
            app.SyntaxTab.Title = 'Syntax';
            app.SyntaxPane = uihtml(app.SyntaxTab);
            app.CHECKSYNTAXButton = uibutton(app.SyntaxTab, 'push');
            app.CHECKSYNTAXButton.ButtonPushedFcn = createCallbackFcn(app, @CHECKSYNTAXButtonPushed, true);
            app.CHECKSYNTAXButton.Text = 'CHECK SYNTAX';
            app.CompileOptionsTab = uitab(app.RightTabGroup);
            app.CompileOptionsTab.Title = 'Compile Options';
            app.chckbx1 = uicheckbox(app.CompileOptionsTab);
            app.chckbx1.Text = 'Force free-form';
            app.chckbx1.Value = true;
            app.UpdateOptionsButton = uibutton(app.CompileOptionsTab, 'push');
            app.UpdateOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateOptionsButtonPushed, true);
            app.UpdateOptionsButton.Text = 'Update Options';
            app.chckbx2 = uicheckbox(app.CompileOptionsTab);
            app.chckbx2.Text = 'Xlist';
            app.chckbx3 = uicheckbox(app.CompileOptionsTab);
            app.chckbx3.Text = {'-g'; ''};
            app.chckbx4 = uicheckbox(app.CompileOptionsTab);
            app.chckbx4.Text = {'-fast'; ''};
            app.chckbx5 = uicheckbox(app.CompileOptionsTab);
            app.chckbx5.Text = '-O[n]';
            app.FolderTreeTab = uitab(app.RightTabGroup);
            app.FolderTreeTab.Title = 'Folder Tree';
            app.Tree = uitree(app.FolderTreeTab);
            app.Node = uitreenode(app.Tree);
            app.Node.Text = 'Node';
            app.Node2 = uitreenode(app.Node);
            app.Node2.Text = 'Node2';
            app.Node3 = uitreenode(app.Node);
            app.Node3.Text = 'Node3';
            app.Node4 = uitreenode(app.Node);
            app.Node4.Text = 'Node4';
            % Show the figure after all components are created
            app.FossilUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FossilUI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FossilUI)
        end
    end
end